classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        EnterFunctionEditFieldLabel     matlab.ui.control.Label
        EnterFunctionEditField          matlab.ui.control.EditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.EditField
        MinimumPrecisionEditFieldLabel  matlab.ui.control.Label
        MinimumPrecisionEditField       matlab.ui.control.EditField
        NewtonRaphsonSingleStepButton   matlab.ui.control.Button
        ChooseTheMethodButtonGroup      matlab.ui.container.ButtonGroup
        BisectionButton                 matlab.ui.control.RadioButton
        FalsePositionButton             matlab.ui.control.RadioButton
        FixedPointButton                matlab.ui.control.RadioButton
        NewtonRaphsonButton             matlab.ui.control.RadioButton
        SecantButton                    matlab.ui.control.RadioButton
        XlowEditFieldLabel              matlab.ui.control.Label
        XlowEditField                   matlab.ui.control.EditField
        XhighEditFieldLabel             matlab.ui.control.Label
        XhighEditField                  matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
        TimeTakenEditFieldLabel         matlab.ui.control.Label
        TimeTakenEditField              matlab.ui.control.EditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.EditField
        TheoreticalerrorEditFieldLabel  matlab.ui.control.Label
        TheoreticalerrorEditField       matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        i=1; % Description
        result=zeros(50,4);
        xl;
        xh;
        xn=0;
        pos;
        neg;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            tic
            f=app.EnterFunctionEditField.Value;
            if app.MaxIterationsEditField.Value~=""
                maxI=str2double(app.MaxIterationsEditField.Value);
            else
                maxI=50;
            end
            if app.MinimumPrecisionEditField.Value~=""
                minP=str2double(app.MinimumPrecisionEditField.Value)
            else
                minP=0.00001;
            end
            syms x;
            func=inline(string(f),'x');
            xl=str2double(app.XlowEditField.Value);
            xh=str2double(app.XhighEditField.Value);
            
            theRoots=solve(str2sym(string(f)));
            theRoots=abs(theRoots);
            
            
            if app.BisectionButton.Value
                if (func(xl)*func(xh))>0
                    app.UITable.ColumnName = {'Error can`t be calculated'};
                else
                    if func(xl)>0
                        pos=xl;
                        neg=xh;
                    else
                        pos=xh;
                        neg=xl;
                    end
                    
                    result=zeros(maxI,6);
                    for i=1:maxI
                        result(i,1)=i;
                        result(i,2)=neg;
                        result(i,3)=pos;
                        xn=(neg+pos)/2;
                        if func(xn)>0
                            pos =xn;
                        else
                            neg=xn;
                        end
                        result(i,4)=xn;
                        result(i,5)=func(xn);
                        if (i~=1)
                            result(i,6)=abs(xn-result(i-1,4));
                            if (result(i,6)<minP|func(xn)==0)
                                break;
                            end
                        end
                        
                    end
                    app.UITable.ColumnName = {'Iteration'; 'x negative'; 'x positixe'; 'x new'; 'F(x new)'; 'Error'};
                    T=table(result(1:i,1),result(1:i,2),result(1:i,3),result(1:i,4),result(1:i,5),result(1:i,6));
                    app.UITable.Data=T;
                    theRoots=abs(theRoots-xn);
                    app.TheoreticalerrorEditField.Value=string(min(theRoots));
                    
                    syms funct(x)
                    funct(x)=func(x);
                    x = linspace(-10,10,1000);
                    plot(app.UIAxes,x,funct(x));
                    for l=1:i
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,result(l,4),funct(result(l,4)),'*-','MarkerSize',12);
                        hold(app.UIAxes,'off');
                    end
                end
            elseif app.FalsePositionButton.Value
                if (func(xl)*func(xh))>0
                    app.UITable.ColumnName = {'Error can`t be calculated'};
                else
                    if func(xl)>0
                        pos=xl;
                        neg=xh;
                    else
                        pos=xh;
                        neg=xl;
                    end
                    
                    result=zeros(maxI,8);
                    for i=1:maxI
                        result(i,1)=i;
                        result(i,2)=neg;
                        result(i,3)=pos;
                        result(i,4)=func(neg);
                        result(i,5)=func(pos);
                        xn=((neg*func(pos))-(pos*func(neg)))/(func(pos)-func(neg))
                        if func(xn)>0
                            pos =xn;
                        else
                            neg=xn;
                        end
                        result(i,6)=xn;
                        result(i,7)=func(xn);
                        if (i~=1)
                            result(i,8)=abs(xn-result(i-1,6));
                            if (abs(xn-result(i-1,6))<minP|func(xn)==0)
                                break;
                            end
                        end
                    end
                    app.UITable.ColumnName = {'Iteration'; 'x negative'; 'x positixe';'F(x negative)'; 'F(x positixe)'; 'x new'; 'F(x new)'; 'Error'};
                    T=table(result(1:i,1),result(1:i,2),result(1:i,3),result(1:i,4),result(1:i,5),result(1:i,6),result(1:i,7),result(1:i,8));
                    app.UITable.Data=T;
                    theRoots=abs(theRoots-xn);
                    app.TheoreticalerrorEditField.Value=string(min(theRoots));
                    
                    syms funct(x)
                    funct(x)=func(x);
                    x = linspace(-10,10,1000);
                    plot(app.UIAxes,x,funct(x));
                    for l=1:i
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,result(l,6),funct(result(l,6)),'*-','MarkerSize',12);
                        hold(app.UIAxes,'off');
                    end
                end
            elseif app.FixedPointButton.Value
                found=0;
                try
                    a=size(root(str2sym(f),x));
                    order=a(1,1);
                    xs=(xl+xh)/2;
                    for q=1:order
                        new=strcat("(",string(f),"+x^",string(q),")^(1/",string(q),")");
                        workingFunc=inline(new,'x');
                        dif=diff(workingFunc(x),x);
                        df=inline(dif,'x');
                        df(xs)
                        if abs(df(xs))<1
                            found=1;
                            break;
                        end
                        new=strcat("-1*(",string(f),"-x^",string(q),")^(1/",string(q),")");
                        workingFunc=inline(new,'x');
                        dif=diff(workingFunc(x),x);
                        df=inline(dif,'x');
                        df(xs)
                        if abs(df(xs))<1
                            found=1;
                            break;
                        end
                    end
                catch
                    try
                        for q=1:1
                            new=strcat("ln(",string(f),"+exp(x))");
                            workingFunc=inline(new,'x');
                            dif=diff(workingFunc(x),x);
                            df=inline(dif,'x');
                            df(xs)
                            if abs(df(xs))<1
                                found=1;
                                break;
                            end
                            new=strcat("asin(",string(f),"+sin(x))");
                            workingFunc=inline(new,'x');
                            dif=diff(workingFunc(x),x);
                            df=inline(dif,'x');
                            df(xs)
                            if abs(df(xs))<1
                                found=1;
                                break;
                            end
                            new=strcat("acos(",string(f),"+cos(x))");
                            workingFunc=inline(new,'x');
                            dif=diff(workingFunc(x),x);
                            df=inline(dif,'x');
                            df(xs)
                            if abs(df(xs))<1
                                found=1;
                                break;
                            end
                            endnew=strcat("atan(",string(f),"+tan(x))");
                            workingFunc=inline(new,'x');
                            dif=diff(workingFunc(x),x);
                            df=inline(dif,'x');
                            df(xs)
                            if abs(df(xs))<1
                                found=1;
                                break;
                            end
                        end
                    catch
                    end
                end
                if found==0
                    app.UITable.ColumnName = {'Error can`t be calculated'};
                end
                if found==1
                    result=zeros(maxI,4);
                    for i=1:maxI
                        result(i,1)=i;
                        result(i,2)=xs;
                        result(i,3)=abs(workingFunc(xs));
                        xs=abs(workingFunc(xs));
                        result(i,4)=abs(result(i,3)-result(i,2))
                        if (result(i,4)<minP|func(xs)==0)
                            break;
                        end
                    end
                    app.UITable.ColumnName = {'Iteration'; 'x old'; 'x new'; 'Error'};
                    T=table(result(1:i,1),result(1:i,2),result(1:i,3),result(1:i,4));
                    app.UITable.Data=T;
                    theRoots=abs(theRoots-xs);
                    app.TheoreticalerrorEditField.Value=string(min(theRoots));
                    
                    syms funct(x)
                    funct(x)=func(x);
                    x = linspace(-10,10,1000);
                    plot(app.UIAxes,x,funct(x));
                    for l=1:i
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,result(l,3),funct(result(l,3)),'*-','MarkerSize',12);
                        hold(app.UIAxes,'off');
                    end
                end
            elseif app.NewtonRaphsonButton.Value
                xn=(xl+xh)/2;
                dif=diff(func(x),x);
                df=inline(dif,'x');
                result=zeros(maxI,4);
                for i=1:maxI
                    result(i,1)=i;
                    result(i,2)=xn;
                    xn=xn-(func(xn)/df(xn));
                    result(i,3)=xn;
                    result(i,4)=abs(result(i,2)-result(i,3));
                    if (result(i,4)<minP)
                        break;
                    end
                end
                app.UITable.ColumnName = {'Iteration'; 'x old'; 'x new'; 'Error'};
                T=table(result(1:i,1),result(1:i,2),result(1:i,3),result(1:i,4));
                app.UITable.Data=T;
                theRoots=abs(theRoots-xn);
                app.TheoreticalerrorEditField.Value=string(min(theRoots));
                
                syms funct(x)
                funct(x)=func(x);
                x = linspace(-10,10,1000);
                plot(app.UIAxes,x,funct(x));
                for l=1:i
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,result(l,3),funct(result(l,3)),'*-','MarkerSize',12);
                    hold(app.UIAxes,'off');
                end
            elseif app.SecantButton.Value
                
                result=zeros(maxI,7);
                for i=1:maxI
                    result(i,1)=i;
                    result(i,2)=xl;
                    result(i,3)=xh;
                    result(i,4)=func(xl);
                    result(i,5)=func(xh);
                    xn=xh-((func(xh)*(xl-xh))/(func(xl)-func(xh)));
                    result(i,6)=xn;
                    xl=xh;
                    xh=xn;
                    if (i~=1)
                        result(i,7)=abs(result(i,6)-result(i,3));
                        if (result(i,7)<minP|func(xn)==0)
                            break;
                        end
                    end
                end
                app.UITable.ColumnName = {'Iteration'; 'x i-1'; 'x i';'F(x i-1)'; 'F(x i)'; 'x i+1'; 'Error'};
                T=table(result(1:i,1),result(1:i,2),result(1:i,3),result(1:i,4),result(1:i,5),result(1:i,6),result(1:i,7));
                app.UITable.Data=T;
                theRoots=abs(theRoots-xn);
                app.TheoreticalerrorEditField.Value=string(min(theRoots));
                
                syms funct(x)
                funct(x)=func(x);
                x = linspace(-10,10,1000);
                plot(app.UIAxes,x,funct(x));
                for l=1:i
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,result(l,6),funct(result(l,6)),'*-','MarkerSize',12);
                    hold(app.UIAxes,'off');
                end
            end
            try
                app.IterationsEditField.Value=string(i);
                timeElapsed = toc;
                app.TimeTakenEditField.Value=string(timeElapsed);
            catch
            end
        end

        % Selection changed function: ChooseTheMethodButtonGroup
        function ChooseTheMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseTheMethodButtonGroup.SelectedObject;
            
        end

        % Button pushed function: NewtonRaphsonSingleStepButton
        function NewtonRaphsonSingleStepButtonPushed(app, event)
            tic
            f=app.EnterFunctionEditField.Value;
            if app.MaxIterationsEditField.Value~=""
                maxI=str2double(app.MaxIterationsEditField.Value);
            else
                maxI=50;
            end
            if app.MinimumPrecisionEditField.Value~=""
                minP=str2double(app.MinimumPrecisionEditField.Value);
            else
                minP=0.00001;
            end
            syms x;
            func=inline(string(f),'x');
            app.xl=str2double(app.XlowEditField.Value);
            app.xh=str2double(app.XhighEditField.Value);
            
            theRoots=solve(str2sym(string(f)));
            theRoots=abs(theRoots);
            if app.xn==0
                app.xn=(app.xl+app.xh)/2;
            end
            dif=diff(func(x),x);
            df=inline(dif,'x');
            result=zeros(maxI,4);
            result=app.result;
            % for i=1:maxI
            result(app.i,1)=app.i;
            result(app.i,2)=app.xn;
            app.xn=app.xn-(func(app.xn)/df(app.xn));
            result(app.i,3)=app.xn;
            result(app.i,4)=abs(result(app.i,2)-result(app.i,3));
            
            % end
            if app.i>maxI
                app.i=1;
            end
            app.UITable.ColumnName = {'Iteration'; 'x old'; 'x new'; 'Error'};
            T=table(result(1:app.i,1),result(1:app.i,2),result(1:app.i,3),result(1:app.i,4));
            app.UITable.Data=T;
            theRoots=abs(theRoots-app.xn);
            app.TheoreticalerrorEditField.Value=string(min(theRoots));
            app.IterationsEditField.Value=string(app.i);
            
            syms funct(x)
            funct(x)=func(x);
            x = linspace(-10,10,1000);
            plot(app.UIAxes,x,funct(x));
            for l=1:app.i
                hold(app.UIAxes,'on');
                plot(app.UIAxes,result(l,3),funct(result(l,3)),'*-','MarkerSize',12);
                hold(app.UIAxes,'off');
            end
            
            app.result=result;
            app.result(app.i,4)
            if (app.result(app.i,4)<minP)&&(app.i~=1)
                app.i=1;
                app.xl=str2double(app.XlowEditField.Value);
                app.xh=str2double(app.XhighEditField.Value);
                app.xn=(app.xl+app.xh)/2;
            else
                app.i=app.i+1;
            end


            timeElapsed = toc;
            app.TimeTakenEditField.Value=string(timeElapsed);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
                app.UITable.ColumnName = {'User selected Cancel'};
            else
                fileId=fopen(fullfile(path,file));
                data = textscan(fileId,'%s');
                fclose(fileId);
                app.EnterFunctionEditField.Value=string(data);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EnterFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.EnterFunctionEditFieldLabel.Text = 'Enter Function';
            app.EnterFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.MaxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.MinimumPrecisionEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumPrecisionEditFieldLabel.Text = 'Minimum Precision';
            app.MinimumPrecisionEditField = uieditfield(app.UIFigure, 'text');
            app.NewtonRaphsonSingleStepButton = uibutton(app.UIFigure, 'push');
            app.NewtonRaphsonSingleStepButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonRaphsonSingleStepButtonPushed, true);
            app.NewtonRaphsonSingleStepButton.Text = 'Newton Raphson Single Step';
            app.ChooseTheMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseTheMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseTheMethodButtonGroupSelectionChanged, true);
            app.ChooseTheMethodButtonGroup.Title = 'Choose The Method';
            app.BisectionButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.BisectionButton.Text = 'Bisection';
            app.BisectionButton.Value = true;
            app.FalsePositionButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.FalsePositionButton.Text = 'False Position';
            app.FixedPointButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.FixedPointButton.Text = 'Fixed Point';
            app.NewtonRaphsonButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.NewtonRaphsonButton.Text = 'Newton Raphson';
            app.SecantButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.SecantButton.Text = 'Secant';
            app.XlowEditFieldLabel = uilabel(app.UIFigure);
            app.XlowEditFieldLabel.Text = 'Xlow';
            app.XlowEditField = uieditfield(app.UIFigure, 'text');
            app.XhighEditFieldLabel = uilabel(app.UIFigure);
            app.XhighEditFieldLabel.Text = 'Xhigh';
            app.XhighEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'output'};
            app.UITable.RowName = {};
            app.TimeTakenEditFieldLabel = uilabel(app.UIFigure);
            app.TimeTakenEditFieldLabel.Text = 'Time Taken';
            app.TimeTakenEditField = uieditfield(app.UIFigure, 'text');
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'text');
            app.TheoreticalerrorEditFieldLabel = uilabel(app.UIFigure);
            app.TheoreticalerrorEditFieldLabel.Text = 'Theoretical error';
            app.TheoreticalerrorEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'input function + iterations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end