classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        EnterFunctionEditFieldLabel     matlab.ui.control.Label
        EnterFunctionEditField          matlab.ui.control.EditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.EditField
        MinimumPrecisionEditFieldLabel  matlab.ui.control.Label
        MinimumPrecisionEditField       matlab.ui.control.EditField
        ChooseTheMethodButtonGroup      matlab.ui.container.ButtonGroup
        GausseliminationButton          matlab.ui.control.RadioButton
        GaussseidelButton               matlab.ui.control.RadioButton
        GaussjordanButton               matlab.ui.control.RadioButton
        UITable                         matlab.ui.control.Table
        TimeTakenEditFieldLabel         matlab.ui.control.Label
        TimeTakenEditField              matlab.ui.control.EditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                tic
                if app.MaxIterationsEditField.Value~=""
                    maxI=str2double(app.MaxIterationsEditField.Value);
                else
                    maxI=50;
                end
                if app.MinimumPrecisionEditField.Value~=""
                    minP=str2double(app.MinimumPrecisionEditField.Value);
                else
                    minP=0.00001;
                end
                
                inf=string(app.EnterFunctionEditField.Value);
                inf=string(inf);
                inf=split(inf,',');
                s=numel(inf);
                syms x [1 s];
                syms y;
                eq=zeros(s,s+1);
                for i=1:s
                    f=inline(inf(i,1),'x');
                    h.f{i}=str2sym(inf(i,1));
                end
                [variables,an]=equationsToMatrix(h.f{:,:});
                eq(:,1:size(variables,2))=variables;
                eq(:,s+1)=an;
                er=0;
                if app.GausseliminationButton.Value
                    for j=1:s
                        for k=j+1:s
                            if (eq(k,j)~=0)
                                eq(k,:)=eq(k,:)*eq(j,j)/eq(k,j)-eq(j,:);
                            end
                        end
                    end
                    for j=1:s
                        for k=j+1:s
                            if eq(k,j)~=0
                                er=1;
                            end
                        end
                    end
                    for i=1:s
                        if eq(i,1:s)==zeros(1,s)
                            er=1;
                        end
                    end
                    if er==0
                        for r=s:-1:1
                            equation=eq(1:r,1:r)*transpose(x(1:r))==eq(1:r,s+1);
                            equation(r);
                            x(r)=solve(equation(r));
                            equation(r)
                            eq(:,s+1)=eq(:,s+1)-eq(:,r)*x(r);
                            syms X [1 s];
                            found=zeros(1,s);
                            for i=1:s
                                for j=1:s
                                    if(eq(i,j)~=0)
                                        found(i)=1;
                                    end
                                end
                            end
                            er=0;
                            for i=1:s
                                if (found(s)==0)
                                    er=1;
                                end
                            end
                        end
                    end
                    if(er==1)
                        app.UITable.ColumnName = {'Error can`t be calculated'};
                        T="";
                        app.UITable.Data=T;
                    else
                        app.UITable.ColumnName = {'Variable'; 'value'};
                        T=table(string(X(:)),string(x(:)));
                        app.UITable.Data=T;
                    end
                    
                    app.IterationsEditField.Value="";
                    plot(app.UIAxes,0,0);
                elseif app.GaussjordanButton.Value
                    
                    max=ones(1,s);
                    for i=1:s
                        for j=i:s
                            if (eq(j,i)>max(i))
                                max(i)=j;
                            end
                        end
                    end
                    temp=zeros(1,s+1);
                    for k=1:s
                        temp(:)=eq(k,:);
                        eq(k,:)=eq(max(1,k),:);
                        eq(max(1,k),:)=temp(:);
                    end
                    for j=1:s
                        for k=j+1:s
                            if eq(k,j)~=0
                                eq(k,:)=eq(k,:)*eq(j,j)/eq(k,j)-eq(j,:);
                            elseif (j+2)>0 && (j+2)>(s+1)
                                for q=j+2:s
                                    eq(k,:)=eq(k,:)*eq(q,j)/eq(k,j)-eq(q,:);
                                end
                            end
                        end
                    end
                    for j=s:-1:1
                        for k=j-1:-1:1
                            if eq(k,j)~=0
                                eq(k,:)=eq(k,:)*eq(j,j)/eq(k,j)-eq(j,:);
                            elseif (j-2)>0 && (j-2)>(s+1)
                                for q=j-2:-1:1
                                    eq(k,:)=eq(k,:)*eq(q,j)/eq(k,j)-eq(q,:);
                                end
                            end
                        end
                    end
                    
                    found=zeros(1,s);
                    for i=1:s
                        for j=1:s
                            %                             eq(i,j)>0.001&&eq(i,j)<-0.001
                            %                             if(eq(i,j)~=0)
                            if(eq(i,j)>0.0001&&eq(i,j)<-0.0001)
                                found(i)=1;
                            end
                        end
                    end
                    er=0;
                    for i=1:s
                        if (found(s)==0)
                            er=1;
                        end
                    end
                    if(er==1)
                        app.UITable.ColumnName = {'Error can`t be calculated'};
                        T="";
                        app.UITable.Data=T;
                    else
                        for j=1:s
                            eq(j,:)=eq(j,:)/eq(j,j);
                        end
                        syms X [1 s];
                        app.UITable.ColumnName = {'Variable'; 'value'};
                        T=table(string(X(:)),eq(:,end));
                        app.UITable.Data=T;
                    end
                    eq
                    
                    %                     app.UITable.ColumnName = {'Variable'; 'value'};
                    %                     T=table(string(X(:)),eq(:,end));
                    %                     app.UITable.Data=T;
                    app.IterationsEditField.Value="";
                    plot(app.UIAxes,0,0);
                elseif app.GaussseidelButton.Value
                    output=eq(:,s+1);
                    currentv=zeros(1,s);
                    result=zeros(maxI,s+1);
                    er1=0;
                    er2=1;
                    for i=1:s
                        sum =0;
                        for j=1:s
                            if (i~=j)
                                sum=sum + abs(eq(i,j));
                            end
                        end
                        if (abs(sum)>abs(eq(i,i)))
                            er1=1;
                        end
                        if(abs(eq(i,i))>abs(sum))
                            er2=0;
                        end
                    end
                    if(er1||er2)
                        er=1;
                    else
                        er=0;
                    end
                    
                    if (er==0)
                        for i=1:maxI
                            for k=1:s
                                sum=output(k);
                                for j=1:s
                                    if(j~=k)
                                        sum=sum-eq(k,j)*currentv(j);
                                    end
                                end
                                currentv(k)=sum/eq(k,k);
                            end
                            result(i,1)=i;
                            result(i,2:s+1)=currentv;
                            if (i~=1)
                                max=abs(result(i,2)-result(i-1,2));
                                for p=3:s+1
                                    if abs(result(i,p)-result(i-1,p))>max
                                        max=abs(result(i,p)-result(i-1,p));
                                    end
                                end
                                if max<minP
                                    break;
                                end
                            end
                        end
                        app.UITable.ColumnName = {'iterations','values(x1->xn)'};
                        T=table(result(1:i,1),result(1:i,2:s+1));
                        app.UITable.Data=T;
                        x = linspace(1,i,i);
                        plot(app.UIAxes,x,result(1:i,2:s+1));
                        app.IterationsEditField.Value=string(i);
                    else
                        app.UITable.ColumnName = {'error can`t be calculated'};
                        T="";
                        app.UITable.Data=T;
                    end
                end
                if (er==0)
                    if app.GausseliminationButton.Value
                        A = string(x(:));
                        fid = fopen('/Users/omarwalid/Documents/College/term 7/Numerical analysis/projects/project 2/output.txt','wt');
                        for ii = 1:size(A,1)
                            fprintf(fid,'%g\t',A(ii,:));
                            fprintf(fid,'\n');
                        end
                        fclose(fid);
                    elseif app.GaussseidelButton.Value
                        A = result(1:i,:);
                        fid = fopen('/Users/omarwalid/Documents/College/term 7/Numerical analysis/projects/project 2/output.txt','wt');
                        for ii = 1:size(A,1)
                            fprintf(fid,'%g\t',A(ii,:));
                            fprintf(fid,'\n');
                        end
                        fclose(fid);
                    end
                end
                timeElapsed = toc;
                app.TimeTakenEditField.Value=string(timeElapsed);
            catch
                app.UITable.ColumnName = {'function can`t be calculated'};
            end
        end

        % Selection changed function: ChooseTheMethodButtonGroup
        function ChooseTheMethodButtonGroupSelectionChanged(app, event)
            
        end

        % Callback function
        function NewtonRaphsonSingleStepButtonPushed(app, event)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
                app.UITable.ColumnName = {'User selected Cancel'};
            else
                fileId=fopen(fullfile(path,file));
                data = textscan(fileId,'%s');
                fclose(fileId);
                app.EnterFunctionEditField.Value=string(data);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EnterFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.EnterFunctionEditFieldLabel.Text = 'Enter Function';
            app.EnterFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.MaxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.MinimumPrecisionEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumPrecisionEditFieldLabel.Text = 'Minimum Precision';
            app.MinimumPrecisionEditField = uieditfield(app.UIFigure, 'text');
            app.ChooseTheMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseTheMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseTheMethodButtonGroupSelectionChanged, true);
            app.ChooseTheMethodButtonGroup.Title = 'Choose The Method';
            app.GausseliminationButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.GausseliminationButton.Text = 'Gauss elimination';
            app.GausseliminationButton.Value = true;
            app.GaussseidelButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.GaussseidelButton.Text = 'Gauss seidel';
            app.GaussjordanButton = uiradiobutton(app.ChooseTheMethodButtonGroup);
            app.GaussjordanButton.Text = 'Gauss jordan';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'output'};
            app.UITable.RowName = {};
            app.TimeTakenEditFieldLabel = uilabel(app.UIFigure);
            app.TimeTakenEditFieldLabel.Text = 'Time Taken';
            app.TimeTakenEditField = uieditfield(app.UIFigure, 'text');
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'input function + iterations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end